classdef ISAC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        BSdistanceEditField             matlab.ui.control.NumericEditField
        BSdistanceEditFieldLabel        matlab.ui.control.Label
        CellularsettingsCheckBox        matlab.ui.control.CheckBox
        numBSEditField                  matlab.ui.control.NumericEditField
        numBSEditFieldLabel             matlab.ui.control.Label
        CDLDCDLELabel                   matlab.ui.control.Label
        fsplfreespaceUMaUMiRMaLabel     matlab.ui.control.Label
        tddSpecialSlotEditField         matlab.ui.control.EditField
        tddSpecialSlotEditFieldLabel    matlab.ui.control.Label
        ChannelModelEditField           matlab.ui.control.EditField
        ChannelModelEditFieldLabel      matlab.ui.control.Label
        pathLossModelEditField          matlab.ui.control.EditField
        pathLossModelEditFieldLabel     matlab.ui.control.Label
        rxAntennaLabel                  matlab.ui.control.Label
        nHEditField_2                   matlab.ui.control.NumericEditField
        nHEditField_2Label              matlab.ui.control.Label
        nVLabel_2                       matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        nVLabel                         matlab.ui.control.Label
        txAntennaLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        nHEditField                     matlab.ui.control.NumericEditField
        nHEditFieldLabel                matlab.ui.control.Label
        txPowerEditField                matlab.ui.control.NumericEditField
        txPowerEditFieldLabel           matlab.ui.control.Label
        rxGainEditField                 matlab.ui.control.NumericEditField
        rxGainEditFieldLabel            matlab.ui.control.Label
        positionEditField               matlab.ui.control.EditField
        positionEditFieldLabel          matlab.ui.control.Label
        tddPatternEditField             matlab.ui.control.EditField
        tddPatternEditFieldLabel        matlab.ui.control.Label
        scsEditField                    matlab.ui.control.NumericEditField
        scsEditFieldLabel               matlab.ui.control.Label
        BandwidthEditField              matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel         matlab.ui.control.Label
        CarrierFrequenceEditField       matlab.ui.control.NumericEditField
        CarrierFrequenceEditFieldLabel  matlab.ui.control.Label
        numFramesEditField              matlab.ui.control.NumericEditField
        numFramesEditFieldLabel         matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_5                      matlab.ui.control.TextArea
        Tab_2                           matlab.ui.container.Tab
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        Button_5                        matlab.ui.control.Button
        positionEditField_3             matlab.ui.control.EditField
        positionEditField_3Label        matlab.ui.control.Label
        poisson2DButton_2               matlab.ui.control.RadioButton
        predefindedButton_2             matlab.ui.control.RadioButton
        numAnts                         matlab.ui.control.NumericEditField
        numAntsEditFieldLabel           matlab.ui.control.Label
        numUEs                          matlab.ui.control.NumericEditField
        numUEsEditFieldLabel            matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        Tab_3                           matlab.ui.container.Tab
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        velocityEditField               matlab.ui.control.EditField
        velocityEditFieldLabel          matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        MovePositionEditField           matlab.ui.control.EditField
        MovePositionEditFieldLabel      matlab.ui.control.Label
        predefinedButton                matlab.ui.control.RadioButton
        randomdirectionButton           matlab.ui.control.RadioButton
        staticButton                    matlab.ui.control.RadioButton
        UIAxes3                         matlab.ui.control.UIAxes
        ButtonGroup                     matlab.ui.container.ButtonGroup
        positionEditField_2             matlab.ui.control.EditField
        positionEditField_2Label        matlab.ui.control.Label
        numAnts_3                       matlab.ui.control.NumericEditField
        heightLabel_2                   matlab.ui.control.Label
        centerCoord_4                   matlab.ui.control.NumericEditField
        centerCoord_yLabel_2            matlab.ui.control.Label
        centerCoord_3                   matlab.ui.control.NumericEditField
        centerCoord_xLabel_2            matlab.ui.control.Label
        radius_2                        matlab.ui.control.NumericEditField
        radiusEditFieldLabel_2          matlab.ui.control.Label
        numAnts_2                       matlab.ui.control.NumericEditField
        heightLabel                     matlab.ui.control.Label
        numUEs_2                        matlab.ui.control.NumericEditField
        numTargetsLabel                 matlab.ui.control.Label
        poisson2DButton                 matlab.ui.control.RadioButton
        predefindedButton               matlab.ui.control.RadioButton
        Tab_4                           matlab.ui.container.Tab
        UELoSTextArea                   matlab.ui.control.TextArea
        UELoSTextAreaLabel              matlab.ui.control.Label
        targetLoSTextArea               matlab.ui.control.TextArea
        targetLoSTextAreaLabel          matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        maxBuildingHeightEditField      matlab.ui.control.NumericEditField
        maxBuildingHeightEditFieldLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        minBuildingHeightEditField      matlab.ui.control.NumericEditField
        minBuildingHeightEditFieldLabel  matlab.ui.control.Label
        streetWidthEditField            matlab.ui.control.NumericEditField
        streetWidthEditFieldLabel       matlab.ui.control.Label
        latitudeEditField               matlab.ui.control.EditField
        latitudeEditFieldLabel          matlab.ui.control.Label
        longitudeEditField              matlab.ui.control.EditField
        longitudeEditFieldLabel         matlab.ui.control.Label
        OpenStreetMapcityLabel          matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        RegionofinterestLabel           matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        Tab_5                           matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %% 参数初始化
            % 时间
            time.numFrames = app.numFramesEditField.Value;

            % 功能
            log.enableTraces     = false;
            log.cqiVisualization = false;
            log.rbVisualization  = false;

            % 参数构造
            [UEs, comResults, senResults, topoParams, targetlists, cellSimuParam, bsParam, schedulingParam, trafficParam, pathlossParam, comChannelParams] = deal(cell(app.numBSEditField.Value, 1));
            
            % 用户
            selectedbutton = app.ButtonGroup_2.SelectedObject;
            Bs = str2num(app.positionEditField.Value);
            for NB = 1:app.numBSEditField.Value
                if strcmp(selectedbutton.Text, 'poisson2D') == 1
                    ue = parameters.user.poisson2D();
                    ue.cellID      = NB;
                    ue.numUEs      = app.numUEs.Value;
                    ue.numAnts     = app.numAnts.Value;
                    ue.radius      = app.BSdistanceEditField.Value/2;
                    ue.centerCoord = [Bs(NB, 1) Bs(NB, 2)];
                    UEs{NB} = ue;
                else
                    ue = parameters.user.predefinedPosition();
                    ue.cellID      = NB;
                    ue.numUEs      = app.numUEs.Value;
                    ue.numAnts     = app.numAnts.Value;
                    ue.position    = str2num(app.positionEditField_3.Value);
                    UEs{NB} = ue;
                end
            end

            % 目标
            selectedbutton1 = app.ButtonGroup_3.SelectedObject;
            selectedbutton2 = app.ButtonGroup.SelectedObject;
            if strcmp(selectedbutton2.Text, 'poisson2D') == 1
                target = parameters.target.poisson2D();
                target.cellID      = 1;
                target.numTargets  = app.numUEs_2.Value;
                target.radius      = app.radius_2.Value;
                target.centerCoord = [app.centerCoord_3.Value app.centerCoord_4.Value];
                target.height      = app.numAnts_2.Value;
                target.rcs         = app.numAnts_3.Value;
                target.velocity    = str2num(app.velocityEditField.Value);
                if strcmp(selectedbutton1.Text, 'random direction') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.RandConstDirection; % moving type
                elseif strcmp(selectedbutton1.Text, 'static') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.ConstPosition; % moving type
                else
                    target.userMovement.type = parameters.setting.UserMovementType.Predefined; % moving type
                    position = str2num(app.MovePositionEditField.Value);
                    target.userMovement.positionList = position';
                end
            else
                target = parameters.target.predefinedPosition();
                target.cellID      = 1;
                target.numTargets  = app.numUEs_2.Value;
                target.position    = str2num(app.positionEditField_2.Value);
                target.height      = app.numAnts_2.Value;
                target.rcs         = app.numAnts_3.Value;
                target.velocity    = str2num(app.velocityEditField.Value);
                if strcmp(selectedbutton1.Text, 'random direction') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.RandConstDirection; % moving type
                elseif strcmp(selectedbutton1.Text, 'static') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.ConstPosition; % moving type
                else
                    target.userMovement.type = parameters.setting.UserMovementType.Predefined; % moving type
                    position = str2num(app.MovePositionEditField.Value);
                    target.userMovement.positionList = position';
                end
            end

            % 基站
            Bp = str2num(app.positionEditField.Value);
            for ii = 1:app.numBSEditField.Value
                bsParams = parameters.baseStation.gNBParameters();
                bsParams.cellID         = ii;
                bsParams.position       = Bp(ii,:);
                % Transmission configurations
                bsParams.duplexMode     = 1;
                bsParams.schedulingType = 0;
                bsParams.dlCarrierFreq  = app.CarrierFrequenceEditField.Value;
                bsParams.ulCarrierFreq  = app.CarrierFrequenceEditField.Value;
                bsParams.dlBandwidth    = app.BandwidthEditField.Value;
                bsParams.ulBandwidth    = app.BandwidthEditField.Value;
                bsParams.scs            = app.scsEditField.Value;
                bsParams.tddPattern     = app.tddPatternEditField.Value;
                bsParams.tddSpecialSlot = str2num(app.tddSpecialSlotEditField.Value);
                % Antenna configurations
                bsParams.txAntenna      = parameters.baseStation.antenna.upa();
                bsParams.txAntenna.nH   = app.nHEditField.Value;
                bsParams.txAntenna.nV   = app.EditField.Value;
                bsParams.rxAntenna      = parameters.baseStation.antenna.upa();
                bsParams.rxAntenna.nH   = app.nHEditField_2.Value;
                bsParams.rxAntenna.nV   = app.EditField_2.Value;
                bsParams.txPower        = app.txPowerEditField.Value;
                bsParams.rxGain         = app.rxGainEditField.Value;
            
                % Sensing configurations
                bsParams.sensing              = parameters.baseStation.sensing.radar();
                bsParams.sensing.detectionfre = 0.5;
                % Attached UEs
                bsParams.attachedUEs     = UEs{ii};
                bsParams.attachedTargets = target;

                bsParam{ii} = bsParams;
    
                % 调度
                schedulingParams = parameters.schedulingStrategies.parameters();
                schedulingParams.schedulerStrategy = 'PF';
                schedulingParams.attachedBS        = bsParam{ii};
                schedulingParam{ii} = schedulingParams;
            
                % 流量
                trafficParams = parameters.trafficModels.parameters();
                trafficParams.trafficModel  = 'On-Off';
                trafficParams.attachedUEs   = UEs{ii};
                trafficParams.dlAppDataRate = 40e3;
                trafficParams.ulAppDataRate = 40e3;
                trafficParam{ii} = trafficParams;
    
                % 路损
                pathlossParams = parameters.pathLossModels.parameters();
                pathlossParams.pathLossModel = app.pathLossModelEditField.Value;
                pathlossParam{ii} = pathlossParams;
            
                % 信道
                comChannel = parameters.channelModels.communication.cdl();
                comChannel.delayProfile = app.ChannelModelEditField.Value;
                comChannel.attachedBS   = bsParam{ii};
                comChannel.attachedUEs  = UEs{ii};
                comChannelParams{ii} = comChannel;

                % 小区参数
                cellSimuParam{ii} = simulation.assignCellSimulationParameters(time, bsParam{ii}, ...
                    schedulingParam{ii}, trafficParam{ii}, pathlossParam{ii}, comChannelParams{ii}, log);

                cellSimuParam{ii}.ueLoSConditions  = str2num(app.UELoSTextArea.Value{ii+1});
                for i = 1:target.numTargets
                    cellSimuParam{ii}.targetLoSConditions{i} = str2num(app.targetLoSTextArea.Value{(ii-1)*target.numTargets+i+1});
                end

                % 目标运动参数
                targetlists{ii} = tools.createtargetlists(bsParam{ii}.attachedTargets, cellSimuParam{ii}.numSlots);
                topoParams{ii} = networkTopology.topo(bsParam{ii}, targetlists{ii});
            end
            
            %% 仿真开始
            for icell = 1:app.numBSEditField.Value
                [comResults{icell},  gNB, cellSimuParams] = simulation.cellSimulation(cellSimuParam{icell});
                senResults{icell} = simulation.cellSensing(gNB, cellSimuParams, topoParams{icell}, targetlists{icell});
            end

            %% 结果展示
            tools.plotComMetricsECDF(comResults);
            if app.numBSEditField.Value>1
                for ii = 1:target.numTargets
                    rmse = zeros(1,20);
                    x    = zeros(1,20);
                    y    = zeros(1,20);
                    z    = zeros(1,20);
                    for i = 1:20
                        [rmse(i), x(i), y(i), z(i)] = tools.kalman_gNB(targetlists{1}{ii}',senResults,app.numBSEditField.Value,i);
                        plot3(x(i), y(i), z(i), 'Parent',app.UIAxes, 'Marker','+','Color','r');
                        hold(app.UIAxes,"on");
                    end
                    plot3(targetlists{1}{ii}(1,:),targetlists{1}{ii}(2,:),targetlists{1}{ii}(3,:),'Parent',app.UIAxes,'Marker','o');
                    hold(app.UIAxes,"on");
%                     plot3(x, y, z, 'Parent',app.UIAxes);
%                     hold(app.UIAxes,"on");
                    plot(rmse, 'Parent',app.UIAxes2);
                    hold(app.UIAxes2, "on");
                end
            else
                for i = 1:target.numTargets
                    plot3(targetlists{1}{i}(1,:),targetlists{1}{i}(2,:),targetlists{1}{i}(3,:),'Parent',app.UIAxes,'Marker','o');
                    hold(app.UIAxes,"on");
                end
                for i = 1:target.numTargets
                    for b = 1:20
                        plot3(senResults{1}{i}.sensingEst(b,1), senResults{1}{i}.sensingEst(b,2), senResults{1}{i}.sensingEst(b,3), 'Parent',app.UIAxes, 'Marker','+','Color','r');
                        hold(app.UIAxes, "on");
                    end
                end
                grid(app.UIAxes,"on");
                for i = 1:target.numTargets
                    plot(senResults{1}{i}.positionRMSE, 'Parent',app.UIAxes2);
                    hold(app.UIAxes2, "on");
                end
    %             legend(app.UIAxes2);
            end

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %% 基站、用户、目标位置参数
            % 用户
            selectedbutton = app.ButtonGroup_2.SelectedObject;
            Bs = str2num(app.positionEditField.Value);
            for NB = 1:app.numBSEditField.Value
                if strcmp(selectedbutton.Text, 'poisson2D') == 1
                    ue = parameters.user.poisson2D();
                    ue.cellID      = NB;
                    ue.numUEs      = app.numUEs.Value;
                    ue.numAnts     = app.numAnts.Value;
                    ue.radius      = app.BSdistanceEditField.Value/2;
                    ue.centerCoord = [Bs(NB, 1) Bs(NB, 2)];
                    UEs{NB} = ue;
                else
                    ue = parameters.user.predefinedPosition();
                    ue.cellID      = NB;
                    ue.numUEs      = app.numUEs.Value;
                    ue.numAnts     = app.numAnts.Value;
                    ue.position    = str2num(app.positionEditField_3.Value);
                    UEs{NB} = ue;
                end
            end

            % 目标
            selectedbutton1 = app.ButtonGroup_3.SelectedObject;
            selectedbutton2 = app.ButtonGroup.SelectedObject;
            if strcmp(selectedbutton2.Text, 'poisson2D') == 1
                target = parameters.target.poisson2D();
                target.cellID      = 1;
                target.numTargets  = app.numUEs_2.Value;
                target.radius      = app.radius_2.Value;
                target.centerCoord = [app.centerCoord_3.Value app.centerCoord_4.Value];
                target.height      = app.numAnts_2.Value;
                target.rcs         = app.numAnts_3.Value;
                target.velocity    = str2num(app.velocityEditField.Value);
                if strcmp(selectedbutton1.Text, 'random direction') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.RandConstDirection; % moving type
                elseif strcmp(selectedbutton1.Text, 'static') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.ConstPosition; % moving type
                else
                    target.userMovement.type = parameters.setting.UserMovementType.Predefined; % moving type
                    position = str2num(app.MovePositionEditField.Value);
                    target.userMovement.positionList = position';
                end
            else
                target = parameters.target.predefinedPosition();
                target.cellID      = 1;
                target.numTargets  = app.numUEs_2.Value;
                target.position    = str2num(app.positionEditField_2.Value);
                target.height      = app.numAnts_2.Value;
                target.rcs         = app.numAnts_3.Value;
                target.velocity    = str2num(app.velocityEditField.Value);
                if strcmp(selectedbutton1.Text, 'random direction') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.RandConstDirection; % moving type
                elseif strcmp(selectedbutton1.Text, 'static') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.ConstPosition; % moving type
                else
                    target.userMovement.type = parameters.setting.UserMovementType.Predefined; % moving type
                    position = str2num(app.MovePositionEditField.Value);
                    target.userMovement.positionList = position';
                end
            end

            targetlists = tools.createtargetlists(target, app.numFramesEditField.Value*app.scsEditField.Value*10/15);
 
            % 场景参数
            roi.origin2D = [0;0];
            roi.xSpan = app.xEditField.Value;
            roi.ySpan = app.yEditField.Value;
            roi.zSpan = app.zEditField.Value;
            roi.xMin  = -app.xEditField.Value/2;
            roi.xMax  = app.xEditField.Value/2;
            roi.yMin  = -app.yEditField.Value/2;
            roi.yMax  = app.yEditField.Value/2;

            osmCity = parameters.city.openStreetMap();
            osmCity.longitude         = str2num(app.longitudeEditField.Value);
            osmCity.latitude          = str2num(app.latitudeEditField.Value);
            osmCity.streetWidth       = app.streetWidthEditField.Value;
            osmCity.minBuildingHeight = app.minBuildingHeightEditField.Value;
            osmCity.maxBuildingHeight = app.maxBuildingHeightEditField.Value;

            %% 场景图
            simuLayout = tools.generateScenario1(roi, osmCity);
            [ueLoS, targetLoS] = tools.plotLos(simuLayout, str2num(app.positionEditField.Value), UEs, targetlists);

            %% LOS情况展示
            % 初始化
            app.UELoSTextArea.Value = '';
            app.targetLoSTextArea.Value = '';
            for ii = 1:app.numBSEditField.Value
                app.UELoSTextArea.Value = [app.UELoSTextArea.Value; num2str(ueLoS(:,ii)')];
            end
            for n = 1:app.numBSEditField.Value
                for i = 1:target.numTargets
                    app.targetLoSTextArea.Value = [app.targetLoSTextArea.Value; num2str(targetLoS{n, i}')];
                end
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % 目标
            selectedbutton1 = app.ButtonGroup_3.SelectedObject;
            selectedbutton2 = app.ButtonGroup.SelectedObject;
            if strcmp(selectedbutton2.Text, 'poisson2D') == 1
                target = parameters.target.poisson2D();
                target.cellID      = 1;
                target.numTargets  = app.numUEs_2.Value;
                target.radius      = app.radius_2.Value;
                target.centerCoord = [app.centerCoord_3.Value app.centerCoord_4.Value];
                target.height      = app.numAnts_2.Value;
                target.rcs         = app.numAnts_3.Value;
                target.velocity    = str2num(app.velocityEditField.Value);
                if strcmp(selectedbutton1.Text, 'random direction') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.RandConstDirection; % moving type
                elseif strcmp(selectedbutton1.Text, 'static') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.ConstPosition; % moving type
                else
                    target.userMovement.type = parameters.setting.UserMovementType.Predefined; % moving type
                    position = str2num(app.MovePositionEditField.Value);
                    target.userMovement.positionList = position';
                end
            else
                target = parameters.target.predefinedPosition();
                target.cellID      = 1;
                target.numTargets  = app.numUEs_2.Value;
                target.position    = str2num(app.positionEditField_2.Value);
                target.height      = app.numAnts_2.Value;
                target.rcs         = app.numAnts_3.Value;
                target.velocity    = str2num(app.velocityEditField.Value);
                if strcmp(selectedbutton1.Text, 'random direction') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.RandConstDirection; % moving type
                elseif strcmp(selectedbutton1.Text, 'static') == 1
                    target.userMovement.type = parameters.setting.UserMovementType.ConstPosition; % moving type
                else
                    target.userMovement.type = parameters.setting.UserMovementType.Predefined; % moving type
                    position = str2num(app.MovePositionEditField.Value);
                    target.userMovement.positionList = position';
                end
            end

            targetlists = tools.createtargetlists(target, app.numFramesEditField.Value*app.scsEditField.Value*10/15);

            for i = 1:target.numTargets
                plot3(targetlists{i}(1,:),targetlists{i}(2,:),targetlists{i}(3,:),'Parent',app.UIAxes3,'Marker','o');
                hold(app.UIAxes3,"on");
            end
            hold(app.UIAxes3,"off");
            grid(app.UIAxes3,"on");
        end

        % Callback function
        function Button_5Pushed(app, event)

        end

        % Value changed function: CellularsettingsCheckBox
        function CellularsettingsCheckBoxValueChanged(app, event)
            value = app.CellularsettingsCheckBox.Value;
            if value == true
                app.positionEditField.Value = '';
                ROI        = [app.xEditField.Value, app.yEditField.Value];
                numCells   = app.numBSEditField.Value;             % Number of cells
                cellRadius = app.BSdistanceEditField.Value/sqrt(3);           % Cell radius (m)
                gNBHeight  = 30;            % Height of gNB antenna
                position = tools.getgNBpos(numCells, cellRadius, gNBHeight, ROI);
                for i = 1:numCells
                    app.positionEditField.Value = [app.positionEditField.Value, num2str(position(i, :))];
                    app.positionEditField.Value = [app.positionEditField.Value, ';'];
                end
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed2(app, event)
            % 用户
            selectedbutton = app.ButtonGroup_2.SelectedObject;
            Bs = str2num(app.positionEditField.Value);
            for NB = 1:app.numBSEditField.Value
                if strcmp(selectedbutton.Text, 'poisson2D') == 1
                    ue = parameters.user.poisson2D();
                    ue.cellID      = NB;
                    ue.numUEs      = app.numUEs.Value;
                    ue.numAnts     = app.numAnts.Value;
                    ue.radius      = app.BSdistanceEditField.Value/2;
                    ue.centerCoord = [Bs(NB, 1) Bs(NB, 2)];
                    UEs{NB} = ue;
                else
                    ue = parameters.user.predefinedPosition();
                    ue.cellID      = NB;
                    ue.numUEs      = app.numUEs.Value;
                    ue.numAnts     = app.numAnts.Value;
                    ue.position    = str2num(app.positionEditField_3.Value);
                    UEs{NB} = ue;
                end
            end

            BSposition = str2num(app.positionEditField.Value);
            A  = 0:pi/3:2*pi;
            px = app.BSdistanceEditField.Value/sqrt(3)*cos(A);  
            py = app.BSdistanceEditField.Value/sqrt(3)*sin(A);
            for i = 1:size(BSposition,1)
                scatter3(BSposition(i,1), BSposition(i,2), BSposition(i,3), 'Parent', app.UIAxes4, 25, 'r', 'filled', 'DisplayName', 'gNBs');
                hold(app.UIAxes4,'on');
                plot(px+BSposition(i,1), py+BSposition(i,2), 'Parent', app.UIAxes4,'Color', 'black', 'linewidth', .5, 'DisplayName', 'Cell Region');
                hold(app.UIAxes4,'on');
            end
            for icell = 1:length(UEs)
                for ii = 1:app.numUEs.Value
                    scatter3(UEs{icell}.position(ii,1), UEs{icell}.position(ii,2), UEs{icell}.position(ii,3), 'Parent', app.UIAxes4, 5, 'b', 'filled', 'DisplayName', 'UEs');
                    hold(app.UIAxes4,'on');
                end
                hold(app.UIAxes4,'on');
            end
            hold(app.UIAxes4,'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '系统参数设置';
            app.TextArea_5 = uitextarea(app.Tab);
            app.TextArea_5.Value = {'仿真参数'};
            app.TextArea_4 = uitextarea(app.Tab);
            app.TextArea_4.Value = {'其他参数'};
            app.TextArea_3 = uitextarea(app.Tab);
            app.TextArea_3.Value = {'天线参数'};
            app.TextArea_2 = uitextarea(app.Tab);
            app.TextArea_2.Value = {'基站参数'};
            app.numFramesEditFieldLabel = uilabel(app.Tab);
            app.numFramesEditFieldLabel.Text = 'numFrames';
            app.numFramesEditField = uieditfield(app.Tab, 'numeric');
            app.numFramesEditField.Value = 1;
            app.CarrierFrequenceEditFieldLabel = uilabel(app.Tab);
            app.CarrierFrequenceEditFieldLabel.Text = 'CarrierFrequence';
            app.CarrierFrequenceEditField = uieditfield(app.Tab, 'numeric');
            app.CarrierFrequenceEditField.Value = 3500000000;
            app.BandwidthEditFieldLabel = uilabel(app.Tab);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEditField = uieditfield(app.Tab, 'numeric');
            app.BandwidthEditField.Value = 20000000;
            app.scsEditFieldLabel = uilabel(app.Tab);
            app.scsEditFieldLabel.Text = 'scs';
            app.scsEditField = uieditfield(app.Tab, 'numeric');
            app.scsEditField.Value = 30;
            app.tddPatternEditFieldLabel = uilabel(app.Tab);
            app.tddPatternEditFieldLabel.Text = 'tddPattern';
            app.tddPatternEditField = uieditfield(app.Tab, 'text');
            app.tddPatternEditField.Value = 'DDDSU';
            app.positionEditFieldLabel = uilabel(app.Tab);
            app.positionEditFieldLabel.Text = 'position';
            app.positionEditField = uieditfield(app.Tab, 'text');
            app.positionEditField.Value = '[0 0 30]';
            app.rxGainEditFieldLabel = uilabel(app.Tab);
            app.rxGainEditFieldLabel.Text = 'rxGain';
            app.rxGainEditField = uieditfield(app.Tab, 'numeric');
            app.rxGainEditField.Value = 25.5;
            app.txPowerEditFieldLabel = uilabel(app.Tab);
            app.txPowerEditFieldLabel.Text = 'txPower';
            app.txPowerEditField = uieditfield(app.Tab, 'numeric');
            app.txPowerEditField.Value = 46;
            app.nHEditFieldLabel = uilabel(app.Tab);
            app.nHEditFieldLabel.Text = 'nH';
            app.nHEditField = uieditfield(app.Tab, 'numeric');
            app.nHEditField.Value = 8;
            app.EditField = uieditfield(app.Tab, 'numeric');
            app.EditField.Value = 8;
            app.txAntennaLabel = uilabel(app.Tab);
            app.txAntennaLabel.Text = 'txAntenna';
            app.nVLabel = uilabel(app.Tab);
            app.nVLabel.Text = 'nV';
            app.EditField_2 = uieditfield(app.Tab, 'numeric');
            app.EditField_2.Value = 4;
            app.nVLabel_2 = uilabel(app.Tab);
            app.nVLabel_2.Text = 'nV';
            app.nHEditField_2Label = uilabel(app.Tab);
            app.nHEditField_2Label.Text = 'nH';
            app.nHEditField_2 = uieditfield(app.Tab, 'numeric');
            app.nHEditField_2.Value = 4;
            app.rxAntennaLabel = uilabel(app.Tab);
            app.rxAntennaLabel.Text = 'rxAntenna';
            app.pathLossModelEditFieldLabel = uilabel(app.Tab);
            app.pathLossModelEditFieldLabel.Text = 'pathLossModel';
            app.pathLossModelEditField = uieditfield(app.Tab, 'text');
            app.pathLossModelEditField.Value = 'UMa';
            app.ChannelModelEditFieldLabel = uilabel(app.Tab);
            app.ChannelModelEditFieldLabel.Text = 'ChannelModel';
            app.ChannelModelEditField = uieditfield(app.Tab, 'text');
            app.ChannelModelEditField.Value = 'CDL-D';
            app.tddSpecialSlotEditFieldLabel = uilabel(app.Tab);
            app.tddSpecialSlotEditFieldLabel.Text = 'tddSpecialSlot';
            app.tddSpecialSlotEditField = uieditfield(app.Tab, 'text');
            app.tddSpecialSlotEditField.Value = '[10 2 2]';
            app.fsplfreespaceUMaUMiRMaLabel = uilabel(app.Tab);
            app.fsplfreespaceUMaUMiRMaLabel.Text = '''fspl'' (free space),  ''UMa'',  ''UMi'',  ''RMa''';
            app.CDLDCDLELabel = uilabel(app.Tab);
            app.CDLDCDLELabel.Text = '''CDL-D'',  ''CDL-E''';
            app.numBSEditFieldLabel = uilabel(app.Tab);
            app.numBSEditFieldLabel.Text = 'numBS';
            app.numBSEditField = uieditfield(app.Tab, 'numeric');
            app.numBSEditField.Value = 1;
            app.CellularsettingsCheckBox = uicheckbox(app.Tab);
            app.CellularsettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CellularsettingsCheckBoxValueChanged, true);
            app.CellularsettingsCheckBox.Text = 'Cellular settings';
            app.BSdistanceEditFieldLabel = uilabel(app.Tab);
            app.BSdistanceEditFieldLabel.Text = 'BSdistance';
            app.BSdistanceEditField = uieditfield(app.Tab, 'numeric');
            app.BSdistanceEditField.Value = 500;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '用户参数设置';
            app.ButtonGroup_2 = uibuttongroup(app.Tab_2);
            app.ButtonGroup_2.Title = '基础参数设置';
            app.UIAxes4 = uiaxes(app.ButtonGroup_2);
            title(app.UIAxes4, 'Cell show')
            xlabel(app.UIAxes4, 'X(m)')
            ylabel(app.UIAxes4, 'Y(m)')
            zlabel(app.UIAxes4, 'Z(m)')
            app.numUEsEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.numUEsEditFieldLabel.Text = 'numUEs';
            app.numUEs = uieditfield(app.ButtonGroup_2, 'numeric');
            app.numUEs.Value = 1;
            app.numAntsEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.numAntsEditFieldLabel.Text = 'numAnts';
            app.numAnts = uieditfield(app.ButtonGroup_2, 'numeric');
            app.numAnts.Value = 2;
            app.predefindedButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.predefindedButton_2.Text = 'predefinded';
            app.poisson2DButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.poisson2DButton_2.Text = 'poisson2D';
            app.poisson2DButton_2.Value = true;
            app.positionEditField_3Label = uilabel(app.ButtonGroup_2);
            app.positionEditField_3Label.Text = 'position';
            app.positionEditField_3 = uieditfield(app.ButtonGroup_2, 'text');
            app.positionEditField_3.Value = '[0 0 0]';
            app.Button_5 = uibutton(app.ButtonGroup_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed2, true);
            app.Button_5.Text = '小区预览';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '目标参数设置';
            app.ButtonGroup = uibuttongroup(app.Tab_3);
            app.ButtonGroup.Title = '基础参数设置';
            app.predefindedButton = uiradiobutton(app.ButtonGroup);
            app.predefindedButton.Text = 'predefinded';
            app.poisson2DButton = uiradiobutton(app.ButtonGroup);
            app.poisson2DButton.Text = 'poisson2D';
            app.poisson2DButton.Value = true;
            app.numTargetsLabel = uilabel(app.ButtonGroup);
            app.numTargetsLabel.Text = 'numTargets';
            app.numUEs_2 = uieditfield(app.ButtonGroup, 'numeric');
            app.numUEs_2.Value = 1;
            app.heightLabel = uilabel(app.ButtonGroup);
            app.heightLabel.Text = 'height';
            app.numAnts_2 = uieditfield(app.ButtonGroup, 'numeric');
            app.numAnts_2.Value = 40;
            app.radiusEditFieldLabel_2 = uilabel(app.ButtonGroup);
            app.radiusEditFieldLabel_2.Text = 'radius';
            app.radius_2 = uieditfield(app.ButtonGroup, 'numeric');
            app.radius_2.Value = 1000;
            app.centerCoord_xLabel_2 = uilabel(app.ButtonGroup);
            app.centerCoord_xLabel_2.Text = 'centerCoord_x';
            app.centerCoord_3 = uieditfield(app.ButtonGroup, 'numeric');
            app.centerCoord_yLabel_2 = uilabel(app.ButtonGroup);
            app.centerCoord_yLabel_2.Text = 'centerCoord_y';
            app.centerCoord_4 = uieditfield(app.ButtonGroup, 'numeric');
            app.heightLabel_2 = uilabel(app.ButtonGroup);
            app.heightLabel_2.Text = 'rcs';
            app.numAnts_3 = uieditfield(app.ButtonGroup, 'numeric');
            app.numAnts_3.Value = 1;
            app.positionEditField_2Label = uilabel(app.ButtonGroup);
            app.positionEditField_2Label.Text = 'position';
            app.positionEditField_2 = uieditfield(app.ButtonGroup, 'text');
            app.positionEditField_2.Value = '[0 0 0]';
            app.ButtonGroup_3 = uibuttongroup(app.Tab_3);
            app.ButtonGroup_3.Title = '运动参数设置';
            app.UIAxes3 = uiaxes(app.ButtonGroup_3);
            title(app.UIAxes3, '轨迹显示')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.staticButton = uiradiobutton(app.ButtonGroup_3);
            app.staticButton.Text = 'static';
            app.staticButton.Value = true;
            app.randomdirectionButton = uiradiobutton(app.ButtonGroup_3);
            app.randomdirectionButton.Text = 'random direction';
            app.predefinedButton = uiradiobutton(app.ButtonGroup_3);
            app.predefinedButton.Text = 'predefined';
            app.MovePositionEditFieldLabel = uilabel(app.ButtonGroup_3);
            app.MovePositionEditFieldLabel.Text = 'MovePosition';
            app.MovePositionEditField = uieditfield(app.ButtonGroup_3, 'text');
            app.MovePositionEditField.Value = '[0 0 0]';
            app.Button_4 = uibutton(app.ButtonGroup_3, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '轨迹预览';
            app.velocityEditFieldLabel = uilabel(app.ButtonGroup_3);
            app.velocityEditFieldLabel.Text = 'velocity';
            app.velocityEditField = uieditfield(app.ButtonGroup_3, 'text');
            app.velocityEditField.Value = '[14]';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '场景参数设置';
            app.xEditFieldLabel = uilabel(app.Tab_4);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.Tab_4, 'numeric');
            app.xEditField.Value = 2000;
            app.RegionofinterestLabel = uilabel(app.Tab_4);
            app.RegionofinterestLabel.Text = 'Region of interest';
            app.yEditFieldLabel = uilabel(app.Tab_4);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.Tab_4, 'numeric');
            app.yEditField.Value = 2000;
            app.zEditFieldLabel = uilabel(app.Tab_4);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.Tab_4, 'numeric');
            app.zEditField.Value = 50;
            app.OpenStreetMapcityLabel = uilabel(app.Tab_4);
            app.OpenStreetMapcityLabel.Text = 'OpenStreetMap™ city';
            app.longitudeEditFieldLabel = uilabel(app.Tab_4);
            app.longitudeEditFieldLabel.Text = 'longitude';
            app.longitudeEditField = uieditfield(app.Tab_4, 'text');
            app.longitudeEditField.Value = '[116.3654, 116.3854]';
            app.latitudeEditFieldLabel = uilabel(app.Tab_4);
            app.latitudeEditFieldLabel.Text = 'latitude';
            app.latitudeEditField = uieditfield(app.Tab_4, 'text');
            app.latitudeEditField.Value = '[39.9498, 39.9698]';
            app.streetWidthEditFieldLabel = uilabel(app.Tab_4);
            app.streetWidthEditFieldLabel.Text = 'streetWidth';
            app.streetWidthEditField = uieditfield(app.Tab_4, 'numeric');
            app.streetWidthEditField.Value = 5;
            app.minBuildingHeightEditFieldLabel = uilabel(app.Tab_4);
            app.minBuildingHeightEditFieldLabel.Text = 'minBuildingHeight';
            app.minBuildingHeightEditField = uieditfield(app.Tab_4, 'numeric');
            app.minBuildingHeightEditField.Value = 5;
            app.Label_3 = uilabel(app.Tab_4);
            app.Label_3.Text = '';
            app.maxBuildingHeightEditFieldLabel = uilabel(app.Tab_4);
            app.maxBuildingHeightEditFieldLabel.Text = 'maxBuildingHeight';
            app.maxBuildingHeightEditField = uieditfield(app.Tab_4, 'numeric');
            app.maxBuildingHeightEditField.Value = 30;
            app.Button_3 = uibutton(app.Tab_4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '场景预览';
            app.targetLoSTextAreaLabel = uilabel(app.Tab_4);
            app.targetLoSTextAreaLabel.Text = 'targetLoS';
            app.targetLoSTextArea = uitextarea(app.Tab_4);
            app.UELoSTextAreaLabel = uilabel(app.Tab_4);
            app.UELoSTextAreaLabel.Text = 'UELoS';
            app.UELoSTextArea = uitextarea(app.Tab_4);
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '结果';
            app.UIAxes = uiaxes(app.Tab_5);
            app.UIAxes2 = uiaxes(app.Tab_5);
            title(app.UIAxes2, 'RMSE')
            xlabel(app.UIAxes2, 'sample')
            ylabel(app.UIAxes2, 'Position RMSE')
            zlabel(app.UIAxes2, 'Z')
            app.Button_2 = uibutton(app.Tab_5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '运行';
            app.Label = uilabel(app.Tab_5);
            app.Label.Text = '无人机点迹';
            app.Label_2 = uilabel(app.Tab_5);
            app.Label_2.Text = '感知结果';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ISAC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end